classdef Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Yes                        matlab.ui.control.Image
        NameFile                   matlab.ui.control.EditField
        SaveButton                 matlab.ui.control.Button
        GraphButton                matlab.ui.control.Button
        ComputeButton              matlab.ui.control.Button
        SymsEnter                  matlab.ui.control.EditField
        YButton                    matlab.ui.control.Button
        XButton                    matlab.ui.control.Button
        Switch                     matlab.ui.control.Switch
        SymbolicCalculationsLabel  matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        Back                       matlab.ui.control.Button
        LeftBracket                matlab.ui.control.Button
        RightBracket               matlab.ui.control.Button
        Sin                        matlab.ui.control.Button
        Cos                        matlab.ui.control.Button
        Dot                        matlab.ui.control.Button
        Tan                        matlab.ui.control.Button
        Enter                      matlab.ui.control.EditField
        One_Divided_X              matlab.ui.control.Button
        Pi                         matlab.ui.control.Button
        sqrtx                      matlab.ui.control.Button
        Title                      matlab.ui.control.Label
        Clear                      matlab.ui.control.Button
        Power                      matlab.ui.control.Button
        B0                         matlab.ui.control.Button
        B9                         matlab.ui.control.Button
        B8                         matlab.ui.control.Button
        B7                         matlab.ui.control.Button
        B6                         matlab.ui.control.Button
        B5                         matlab.ui.control.Button
        B4                         matlab.ui.control.Button
        B3                         matlab.ui.control.Button
        B2                         matlab.ui.control.Button
        Substraction               matlab.ui.control.Button
        Multiplying                matlab.ui.control.Button
        B1                         matlab.ui.control.Button
        Division                   matlab.ui.control.Button
        Sum                        matlab.ui.control.Button
        Equals                     matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        equalsPushed = false
        graphComputePushed = false % Description
        xArray = linspace(-1,1,10); % Description
        symsResult = []; % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Equals
        function EqualsPushed(app, event)
            if (app.Switch.Value == "On")
                oldString = app.SymsEnter.Value;
                newString = "==";
                textString = oldString + newString;
                app.SymsEnter.Value = textString;
            else
                textString = app.Enter.Value;
                textString = eval(textString);
                app.Enter.Value = num2str(textString);
                app.equalsPushed = true;
            end
        end

        % Button pushed function: B1
        function B1Pushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "1";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: B2
        function B2ButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "2";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: B3
        function B3ButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "3";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: B4
        function B4ButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "4";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: B5
        function B5ButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "5";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: B6
        function B6ButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "6";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: B7
        function B7ButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "7";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: B8
        function B8ButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "8";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: B9
        function B9ButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "9";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: B0
        function B0ButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "0";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: Sum
        function SumButtonPushed(app, event)
            app.equalsPushed = false;
            app.graphComputePushed = false;
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "+";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: Substraction
        function SubstractionButtonPushed(app, event)
            app.equalsPushed = false;
            app.graphComputePushed = false;
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "-";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: Multiplying
        function MultiplyingButtonPushed(app, event)
            app.equalsPushed = false;
            app.graphComputePushed = false;
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "*";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: Division
        function DivisionButtonPushed(app, event)
            app.equalsPushed = false;
            app.graphComputePushed = false;
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "/";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: Power
        function PowerButtonPushed(app, event)
            app.equalsPushed = false;
            app.graphComputePushed = false;
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "^";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            app.Enter.Value = "";
            app.SymsEnter.Value = "";
        end

        % Button pushed function: Pi
        function PiButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "pi";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: Tan
        function TanButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = strcat(oldString, "tan(");
            textString = newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: Sin
        function SinButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = strcat(oldString, "sin(");
            textString = newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: Cos
        function CosButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = strcat(oldString, "cos(");
            textString = newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: Dot
        function DotButtonPushed(app, event)
            app.equalsPushed = false;
            app.graphComputePushed = false;
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = ".";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: RightBracket
        function RightBracketButtonPushed(app, event)
            app.equalsPushed = false;
            app.graphComputePushed = false;
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = ")";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: LeftBracket
        function LeftBracketButtonPushed(app, event)
            app.equalsPushed = false;
            app.graphComputePushed = false;
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "(";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: One_Divided_X
        function One_Divided_XButtonPushed(app, event)
            if (app.Switch.Value == "On")
                app.SymsEnter.Value = "This Function is Unavailable";
            else 
                text = str2double(app.Enter.Value);
                app.Enter.Value = num2str(1/text);
            end
            app.equalsPushed = true;
        end

        % Button pushed function: sqrtx
        function sqrtxButtonPushed(app, event)
            app.equalsPushed = false;
            app.graphComputePushed = false;
            LightIsOn = app.Switch.Value == "On";
            oldString = app.Enter.Value;
            if (LightIsOn)
                oldString = app.SymsEnter.Value;
            end
            newString = "sqrt(";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            else
                app.Enter.Value = textString;
            end
        end

        % Button pushed function: Back
        function BackButtonPushed(app, event)
            LightIsOn = app.Switch.Value == "On";
            if (LightIsOn)
                if (app.SymsEnter.Value == "")
                    return;
                end
                text = app.SymsEnter.Value;
                text = text(1:strlength(text)-1);
                app.SymsEnter.Value = text;
            end
            if (app.Enter.Value == "")
                return;
            end
            text = app.Enter.Value;
            text = text(1:strlength(text)-1);
            app.Enter.Value = text;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.Enter.Value = "";
            app.SymsEnter.Value = "";
            if (value == "On") 
                app.Lamp.Enable = "On";
            else
                app.Lamp.Enable = "Off";
            end
        end

        % Button pushed function: XButton
        function XButtonButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.SymsEnter.Value;
            newString = "x";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            end
        end

        % Button pushed function: YButton
        function YButtonButtonPushed(app, event)
            if (app.equalsPushed || app.graphComputePushed) 
                ClearButtonPushed(app, event);
                app.equalsPushed = false;
                app.graphComputePushed = false;
            end
            LightIsOn = app.Switch.Value == "On";
            oldString = app.SymsEnter.Value;
            newString = "y";
            textString = oldString + newString;
            if (LightIsOn)
                app.SymsEnter.Value = textString;
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            syms x y
            app.symsResult = [];
            f = app.SymsEnter.Value;
            symRes = str2sym(f);
            if (contains(f, "y"))
                S = solve(symRes, y);
                result = "y = ";
                for i = 1:length(S)
                    if (i == 1 && length(S) == 1)
                        result = strcat(result, string(S(i)));
                    else
                        if (i == 1 && length(S) ~= 1)
                            result = strcat(result, string(S(i)),",");
                        else
                            if (i == length(S))
                                result = strcat(result, "y = ", string(S(i)));
                            else
                                result = strcat(result, "y = ", string(S(i)),",");
                            end
                        end
                    end
                end
            else
                S = solve(symRes, x);
                result = "x = ";
                for i = 1:length(S)
                    if (i == 1 && length(S) == 1)
                        result = strcat(result, string(S(i)));
                    else
                        if (i == 1 && length(S) ~= 1)
                            result = strcat(result, string(S(i)),",");
                        else
                            if (i == length(S))
                                result = strcat(result, "x = ", string(S(i)));
                            else
                                result = strcat(result, "x = ", string(S(i)),",");
                            end
                        end
                    end
                end 
            end
            app.SymsEnter.Value = result;
            app.equalsPushed = true;
            app.graphComputePushed = true;
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            syms x y;
            app.symsResult = [];
            if (~app.graphComputePushed)
                ComputeButtonPushed(app,event);
            end
            f = app.SymsEnter.Value;
            if (f == "") 
                return;
            end
            symRes = split(f,',');
            condition = contains(f, "y");
            if (condition)
                try
                    for i = 1:length(symRes)
                        S = solve(str2sym(symRes(i)), y);
                        b = double(subs(S,x,app.xArray));
                        app.symsResult = cat(1,app.symsResult,b);
                        plot(app.UIAxes,app.xArray,b);
                        hold(app.UIAxes, "on");
                    end
                    hold(app.UIAxes, "off");
                catch 
                    app.SymsEnter.Value = "Error! Try more";
                end    
            else
                try
                    
                    for i = 1:length(symRes)
                        S = solve(str2sym(symRes(i)), x);
                        b = repelem(double(S),10);
                        app.symsResult = cat(1,app.symsResult,b);
                        plot(app.UIAxes,b,app.xArray);
                        hold(app.UIAxes, "on");
                    end
                    hold(app.UIAxes, "off");
                catch 
                    app.SymsEnter.Value = "Error! Try more";
                end
            end
            app.equalsPushed = true;
            app.graphComputePushed = true;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if(app.Switch.Value == "Off") 
                return
            end
            a = app.xArray;
            b = app.symsResult;
            equation = app.SymsEnter.Value;
            save(app.NameFile.Value, 'a', 'b', 'equation')
            app.Yes.Visible = true;
            pause(1)
            app.Yes.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'График функции y(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Equals = uibutton(app.UIFigure, 'push');
            app.Equals.ButtonPushedFcn = createCallbackFcn(app, @EqualsPushed, true);
            app.Equals.Text = '=';
            app.Sum = uibutton(app.UIFigure, 'push');
            app.Sum.ButtonPushedFcn = createCallbackFcn(app, @SumButtonPushed, true);
            app.Sum.Text = '+';
            app.Division = uibutton(app.UIFigure, 'push');
            app.Division.ButtonPushedFcn = createCallbackFcn(app, @DivisionButtonPushed, true);
            app.Division.Text = '/';
            app.B1 = uibutton(app.UIFigure, 'push');
            app.B1.ButtonPushedFcn = createCallbackFcn(app, @B1Pushed, true);
            app.B1.Text = '1';
            app.Multiplying = uibutton(app.UIFigure, 'push');
            app.Multiplying.ButtonPushedFcn = createCallbackFcn(app, @MultiplyingButtonPushed, true);
            app.Multiplying.Text = '*';
            app.Substraction = uibutton(app.UIFigure, 'push');
            app.Substraction.ButtonPushedFcn = createCallbackFcn(app, @SubstractionButtonPushed, true);
            app.Substraction.Text = '-';
            app.B2 = uibutton(app.UIFigure, 'push');
            app.B2.ButtonPushedFcn = createCallbackFcn(app, @B2ButtonPushed, true);
            app.B2.Text = '2';
            app.B3 = uibutton(app.UIFigure, 'push');
            app.B3.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3.Text = '3';
            app.B4 = uibutton(app.UIFigure, 'push');
            app.B4.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4.Text = '4';
            app.B5 = uibutton(app.UIFigure, 'push');
            app.B5.ButtonPushedFcn = createCallbackFcn(app, @B5ButtonPushed, true);
            app.B5.Text = '5';
            app.B6 = uibutton(app.UIFigure, 'push');
            app.B6.ButtonPushedFcn = createCallbackFcn(app, @B6ButtonPushed, true);
            app.B6.Text = '6';
            app.B7 = uibutton(app.UIFigure, 'push');
            app.B7.ButtonPushedFcn = createCallbackFcn(app, @B7ButtonPushed, true);
            app.B7.Text = '7';
            app.B8 = uibutton(app.UIFigure, 'push');
            app.B8.ButtonPushedFcn = createCallbackFcn(app, @B8ButtonPushed, true);
            app.B8.Text = '8';
            app.B9 = uibutton(app.UIFigure, 'push');
            app.B9.ButtonPushedFcn = createCallbackFcn(app, @B9ButtonPushed, true);
            app.B9.Text = '9';
            app.B0 = uibutton(app.UIFigure, 'push');
            app.B0.ButtonPushedFcn = createCallbackFcn(app, @B0ButtonPushed, true);
            app.B0.Text = '0';
            app.Power = uibutton(app.UIFigure, 'push');
            app.Power.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.Power.Text = '^';
            app.Clear = uibutton(app.UIFigure, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'Clear';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Calculator';
            app.sqrtx = uibutton(app.UIFigure, 'push');
            app.sqrtx.ButtonPushedFcn = createCallbackFcn(app, @sqrtxButtonPushed, true);
            app.sqrtx.Text = 'sqrt(x)';
            app.Pi = uibutton(app.UIFigure, 'push');
            app.Pi.ButtonPushedFcn = createCallbackFcn(app, @PiButtonPushed, true);
            app.Pi.Text = 'pi';
            app.One_Divided_X = uibutton(app.UIFigure, 'push');
            app.One_Divided_X.ButtonPushedFcn = createCallbackFcn(app, @One_Divided_XButtonPushed, true);
            app.One_Divided_X.Text = '1/x';
            app.Enter = uieditfield(app.UIFigure, 'text');
            app.Tan = uibutton(app.UIFigure, 'push');
            app.Tan.ButtonPushedFcn = createCallbackFcn(app, @TanButtonPushed, true);
            app.Tan.Text = 'tan';
            app.Dot = uibutton(app.UIFigure, 'push');
            app.Dot.ButtonPushedFcn = createCallbackFcn(app, @DotButtonPushed, true);
            app.Dot.Text = '.';
            app.Cos = uibutton(app.UIFigure, 'push');
            app.Cos.ButtonPushedFcn = createCallbackFcn(app, @CosButtonPushed, true);
            app.Cos.Text = 'cos';
            app.Sin = uibutton(app.UIFigure, 'push');
            app.Sin.ButtonPushedFcn = createCallbackFcn(app, @SinButtonPushed, true);
            app.Sin.Text = 'sin';
            app.RightBracket = uibutton(app.UIFigure, 'push');
            app.RightBracket.ButtonPushedFcn = createCallbackFcn(app, @RightBracketButtonPushed, true);
            app.RightBracket.Text = ')';
            app.LeftBracket = uibutton(app.UIFigure, 'push');
            app.LeftBracket.ButtonPushedFcn = createCallbackFcn(app, @LeftBracketButtonPushed, true);
            app.LeftBracket.Text = '(';
            app.Back = uibutton(app.UIFigure, 'push');
            app.Back.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.Back.Text = 'Back';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Символьные'; 'вычисления'};
            app.SymbolicCalculationsLabel = uilabel(app.UIFigure);
            app.SymbolicCalculationsLabel.Text = 'Symbolic Calculations';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonButtonPushed, true);
            app.XButton.Text = 'x';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonButtonPushed, true);
            app.YButton.Text = 'y';
            app.SymsEnter = uieditfield(app.UIFigure, 'text');
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NameFile = uieditfield(app.UIFigure, 'text');
            app.NameFile.Value = 'New_Data_File';
            app.Yes = uiimage(app.UIFigure);
            app.Yes.Visible = 'off';
            app.Yes.ImageSource = 'галочка.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end